classdef Imaging_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ArchiveRemotelyButton          matlab.ui.control.Button
        ArchiveLocallyButton           matlab.ui.control.Button
        Section6ArchiveRemotelyLabel   matlab.ui.control.Label
        Section5ArchiveLocallyLabel    matlab.ui.control.Label
        SendtoAWSDropDown              matlab.ui.control.DropDown
        SendtoAWSDropDownLabel         matlab.ui.control.Label
        DeleteResumeUploadingfolderandfilesPanel  matlab.ui.container.Panel
        ResumeButton                   matlab.ui.control.Button
        CheckDeleteButton              matlab.ui.control.Button
        Cycle_numSpinner_4             matlab.ui.control.Spinner
        Cycle_numSpinner_4Label        matlab.ui.control.Label
        CycletypeDropDown_2            matlab.ui.control.DropDown
        CycletypeDropDown_2Label       matlab.ui.control.Label
        DatafolderEditField_2          matlab.ui.control.EditField
        DatafolderEditField_2Label     matlab.ui.control.Label
        ChangepixeisizeCheckBox        matlab.ui.control.CheckBox
        ChangeCheckBox                 matlab.ui.control.CheckBox
        SlicePerSlideEditField         matlab.ui.control.EditField
        SlicePerSlideEditFieldLabel    matlab.ui.control.Label
        Section1ImagingSettingLabel_2  matlab.ui.control.Label
        Step2CheckAlignmentButton      matlab.ui.control.Button
        WarningTextAreaLabel_2         matlab.ui.control.Label
        WarningTextArea                matlab.ui.control.TextArea
        MessageTextAreaLabel_2         matlab.ui.control.Label
        MessageTextArea                matlab.ui.control.TextArea
        pixelsizeEditField             matlab.ui.control.EditField
        pixelsizeEditFieldLabel        matlab.ui.control.Label
        remote_rootEditField           matlab.ui.control.EditField
        remote_rootEditFieldLabel      matlab.ui.control.Label
        remote_loginEditField          matlab.ui.control.EditField
        remote_loginEditFieldLabel     matlab.ui.control.Label
        InputcycleinformationButton    matlab.ui.control.Button
        Cycle_numSpinner_3             matlab.ui.control.Spinner
        Cycle_numSpinner_3Label        matlab.ui.control.Label
        Section4MaxprojectionLabel     matlab.ui.control.Label
        Section3CreateTilesLabel       matlab.ui.control.Label
        Section2FocusingLabel          matlab.ui.control.Label
        CreateTilesButton              matlab.ui.control.Button
        GenerateMaxProjectionButton    matlab.ui.control.Button
        Step1CheckFocusingButton       matlab.ui.control.Button
        init_cycle_typeDropDown        matlab.ui.control.DropDown
        init_cycle_typeLabel           matlab.ui.control.Label
        CycletypeDropDown              matlab.ui.control.DropDown
        CycletypeDropDownLabel         matlab.ui.control.Label
        ExperimentIDEditField          matlab.ui.control.EditField
        ExperimentIDEditFieldLabel     matlab.ui.control.Label
        TechnicianDropDown             matlab.ui.control.DropDown
        TechnicianDropDownLabel        matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    properties (Access = private)
        acquisition_root = 'D:\barseq'; % Root of path for acquisition
        maxproj_root = 'D:\maxproj'; % Root of path for storing max projections
        remote_storage = [];  %'imagestorage@barseqstorage0'; % Remote storage server, in the format of username@servername
        person="Diana Ravens"; % The person running the experiment
        experiment_id=''; % Folder in which the experiment is stored under root path(s).
        maxproj_folder=''; % Remote der storing the maxprojection files
        aws_management_dir=[];%'C:\Users\Chen Lab CREST\Desktop\AWS_Management'; % Local working folder for managing AWS transfer    
        phonebook=["Diana Ravens","1234567890","tmobile"];
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InputcycleinformationButton
        function InputcycleinformationButtonPushed(app, event)
            app.experiment_id=char(app.ExperimentIDEditField.Value);
            app.MessageTextArea.Value='';
            app.WarningTextArea.Value='';
            cycletype=char(app.CycletypeDropDown.Value);
            app.person=char(app.TechnicianDropDown.Value);
            cyclenum=app.Cycle_numSpinner_3.Value;
            datafolderfull=fullfile(app.acquisition_root,app.experiment_id);
            if ~exist(datafolderfull,'dir')
               mkdir(datafolderfull)
               app.MessageTextArea.Value{1}=['Data folder:',datafolderfull,' created.'];
            else
               app.WarningTextArea.Value=['Folder: ',datafolderfull, ' already exists! First cycle?'];
               app.WarningTextArea.FontColor=[1 0 0];
               app.MessageTextArea.Value{1}='';
            end 
         
            app.MessageTextArea.Value{2}=['Please confirm experiment folder:',datafolderfull];
            app.MessageTextArea.Value{3}=['Please confirm cycle name: ',[cycletype,num2str(cyclenum,'%.2u')]];
            app.MessageTextArea.Value{4}=['Please confirm technician who is running the experiment: ',app.person];
            cd(datafolderfull)
        end

        % Button pushed function: Step1CheckFocusingButton
        function Step1CheckFocusingButtonPushed(app, event)
            app.InputcycleinformationButton.Enable='off';
            app.Step1CheckFocusingButton.Enable='off';
            app.Step2CheckAlignmentButton.Enable='off';
            app.CreateTilesButton.Enable='off';
            app.GenerateMaxProjectionButton.Enable='off';
            app.experiment_id=char(app.ExperimentIDEditField.Value);
            path=fullfile(app.acquisition_root, app.experiment_id);
            cd(path)
            app.MessageTextArea.Value='';
            app.WarningTextArea.Value='';
            f = waitbar(0,'Please wait...');
            cycletype=char(app.CycletypeDropDown.Value);
            cyclenum=app.Cycle_numSpinner_3.Value;
            cycle_name=[cycletype, num2str(cyclenum,'%.2u')];
            app.MessageTextArea.Value=['Calculating focus for ',[cycle_name,'.csv'],'...'];
            pos_per_slice=4; % don't change this
            focus_folder=['focus', cycle_name]; % e.g. focus + geneseq + 00 +
            dicch=2;
            focusch=1;
            waitbar(.33,f,'processing your data');
            try
                offset=niefindfluorfocusfast1([cycle_name,'.csv'], focus_folder, -30, 30, 1.5, focusch,dicch);
            catch ME % ME is of the class MException (in-built Matlab)
                str = sprintf(['Something is wrong with input files or setting. \n' 'Matlab says: \n' ME.message]);
                f_error = app.UIFigure;
                f_error.WindowStyle = 'modal';
                uialert(f_error, str, 'Input Files or Setting Error', 'Icon', 'error');
                close(f);
                app.InputcycleinformationButton.Enable='on';
                app.Step1CheckFocusingButton.Enable='on';
                app.Step2CheckAlignmentButton.Enable='on';
                app.CreateTilesButton.Enable='on';
                app.GenerateMaxProjectionButton.Enable='on';
                return
            end
                
            waitbar(.66,f,'plotting your data');
            plot(app.UIAxes,offset,'-k','LineWidth',1);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,[1 numel(offset)],[20 20],'--r','LineWidth',1);
            plot(app.UIAxes,[1 numel(offset)],[-20 -20],'--r','LineWidth',1);
            hold(app.UIAxes, 'off');
            app.UIAxes.XLim=[1 numel(offset)];
            app.UIAxes.YLim=[-30 30];
            xlabel(app.UIAxes,'Positions')
            ylabel(app.UIAxes,'Focus shift');

            waitbar(1,f,'Done!');
            close(f)
            app.MessageTextArea.Value= ['Done! Focused positions saved to ',['offset',cycle_name,'.csv']];
            I=abs(offset)>20;
            if sum(I)>0
               app.WarningTextArea.Value='Please check the following positions with extreme shifts:';
               app.WarningTextArea.FontColor=[1 0 0];
               idx=find(I);
               app.MessageTextArea.Value=sprintf('Position %d: Shift %d\n',[idx,round(offset(idx))]');
           else
               app.MessageTextArea.Value= 'All points appear to focus fine.';
           end
            app.InputcycleinformationButton.Enable='on';
            app.Step1CheckFocusingButton.Enable='on';
            app.Step2CheckAlignmentButton.Enable='on';
            app.CreateTilesButton.Enable='on';
            app.GenerateMaxProjectionButton.Enable='on';
          
           
        end

        % Button pushed function: Step2CheckAlignmentButton
        function Step2CheckAlignmentButtonPushed(app, event)
            app.InputcycleinformationButton.Enable='off';
            app.Step1CheckFocusingButton.Enable='off';
            app.Step2CheckAlignmentButton.Enable='off';
            app.CreateTilesButton.Enable='off';
            app.GenerateMaxProjectionButton.Enable='off';
            
            
            app.experiment_id=char(app.ExperimentIDEditField.Value);
            path=fullfile(app.acquisition_root,app.experiment_id);
            cd(path) 
         
            app.WarningTextArea.Value='';
            app.MessageTextArea.Value='';
            f = waitbar(0,'Please wait...');
            app.MessageTextArea.Value='Registering xy position using DIC image...';
            cycletype=char(app.CycletypeDropDown.Value);
            cyclenum=app.Cycle_numSpinner_3.Value;
            cycle_name=[cycletype,num2str(cyclenum,'%.2u')];
            focus_folder=['focus',cycle_name];
            pixelsize=str2double(app.pixelsizeEditField.Value);
            init_cycle=[char(app.init_cycle_typeDropDown.Value),'00'];
            str=app.SlicePerSlideEditField.Value;
            tmp = regexp(str,'([^ ,:]*)','tokens');
            out = cat(2,tmp{:});
            slice_per_slide_1=str2double(out);
            waitbar(.33,f,'processing your data');
            try
               xy_translation=niematchdicxy1_gui(app, ['offset',cycle_name,'.csv'],['dic',focus_folder],['dicfocus',init_cycle],pixelsize,slice_per_slide_1,0);
            catch ME % ME is of the class MException (in-built Matlab)
                str = sprintf(['Something is wrong with input files or setting. \n' 'Matlab says: \n' ME.message]);
                f_error = app.UIFigure;
                f_error.WindowStyle = 'modal';
                uialert(f_error, str, 'Input Files or Setting Error', 'Icon', 'error');
                close(f);
                app.InputcycleinformationButton.Enable='on';
                app.Step1CheckFocusingButton.Enable='on';
                app.Step2CheckAlignmentButton.Enable='on';
                app.CreateTilesButton.Enable='on';
                app.GenerateMaxProjectionButton.Enable='on';
                
                return
             end
             waitbar(1,f,'Done!');
             close(f);
             app.MessageTextArea.Value=[app.MessageTextArea.Value(:)', {'Done!'}];
             app.MessageTextArea.Value=[app.MessageTextArea.Value(:)', { sprintf('Pos %u, x =  %d, y = %d\n',[(1:size(xy_translation,1)); int16(round(xy_translation))'])}];
             
             scatter(app.UIAxes2,xy_translation(:,1)',xy_translation(:,2)',10,1:size(xy_translation,1),'filled');
             app.UIAxes2.XLim=[min(xy_translation(:,1))-5, max(xy_translation(:,1))+5];
             app.UIAxes2.YLim=[min(xy_translation(:,2))-5, max(xy_translation(:,2))+5] ;
             xlabel(app.UIAxes2,'X shift (pixels)');
             ylabel(app.UIAxes2,'Y shift(pixels)');
             app.InputcycleinformationButton.Enable='on';
             app.Step1CheckFocusingButton.Enable='on';
             app.Step2CheckAlignmentButton.Enable='on';
             app.CreateTilesButton.Enable='on';
             app.GenerateMaxProjectionButton.Enable='on';
          
        end

        % Button pushed function: CreateTilesButton
        function CreateTilesButtonPushed(app, event)
            app.InputcycleinformationButton.Enable='off';
            app.Step1CheckFocusingButton.Enable='off';
            app.Step2CheckAlignmentButton.Enable='off';
            app.CreateTilesButton.Enable='off';
            app.GenerateMaxProjectionButton.Enable='off';
          
            app.experiment_id=char(app.ExperimentIDEditField.Value);
            path=fullfile(app.acquisition_root, app.experiment_id);
            cd(path)
            app.MessageTextArea.Value='';
            app.WarningTextArea.Value='';
            cycletype=char(app.CycletypeDropDown.Value);
            cyclenum=app.Cycle_numSpinner_3.Value;
            cycle_name=[cycletype,num2str(cyclenum,'%.2u')];
            pixelsize=str2double(app.pixelsizeEditField.Value);
            niecreatetilesuneven(['regoffset',cycle_name,'.csv'],3200,pixelsize,20);
            filename=append('tiledregoffset',cycle_name,'.csv');
            tiledreoffset=readtable(filename);
            row=height(tiledreoffset);
            app.MessageTextArea.Value{1}=sprintf('Tiled position list saved to %s\n',['tiledregoffset',cycle_name,'.csv']);
            app.MessageTextArea.Value{2}=sprintf('Position information saved to %s\n',['Posinfo_regoffset',cycle_name,'.mat']);
            app.MessageTextArea.Value{3}='Checking if there is enough space for current cycle...';
            
            acquisition_avail_space=getFreeSpace(app.acquisition_root);
            maxproj_avail_space=getFreeSpace(app.maxproj_root);
            cmd=['for /f "tokens=4 delims= " %i in (''ssh ', app.remote_storage,' df ^| findstr md1'') DO @echo %i'];
            [status,cmdout]=system(cmd);
            [acquisition_space_need,maxproj_space_need,archive_space_need]=CalculateNeedSpace(cycle_name,row);
            warn={};
            if acquisition_space_need>=acquisition_avail_space
               warn{end+1}=(['Path ',app.acquisition_root,' does not have enough space for current cycle!']);
            end
            if maxproj_space_need>=maxproj_avail_space
               warn{end+1}=(['Path ', app.maxproj_root,' does not have enough space for current cycle!']);
            end
            if status==0
                archive_avail_space=str2double(cmdout)*1024;
                if archive_space_need>=archive_avail_space
                   warn{end+1}=('Archive drive does not have enough space for current cycle!');
                end
            else
                warn{end+1}='Error in accessing remote storage server!';
            end
            
            TF = isempty(warn);
            if TF ==1
                app.WarningTextArea.Value='';
                app.MessageTextArea.Value{4}='All hard drives have enough space for current cycle!';
            elseif TF==0
                app.WarningTextArea.Value=sprintf('%s \n',warn{:});
                app.MessageTextArea.Value{4}='Check warning message!';
            end
            app.InputcycleinformationButton.Enable='on';
            app.Step1CheckFocusingButton.Enable='on';
            app.Step2CheckAlignmentButton.Enable='on';
            app.CreateTilesButton.Enable='on';
            app.GenerateMaxProjectionButton.Enable='on';
          
        end

        % Button pushed function: GenerateMaxProjectionButton
        function GenerateMaxProjectionButtonPushed(app, event)
            app.InputcycleinformationButton.Enable='off';
            app.Step1CheckFocusingButton.Enable='off';
            app.Step2CheckAlignmentButton.Enable='off';
            app.CreateTilesButton.Enable='off';
            app.GenerateMaxProjectionButton.Enable='off';
           
            app.experiment_id=char(app.ExperimentIDEditField.Value);
            path=fullfile(app.acquisition_root,app.experiment_id);
            cd(path)

            f = waitbar(0,'Please wait...');
            %load('tformhyb.mat','tformhyb');
            %load('tformseq.mat','tformseq');
            load('tformsingle.mat','tformsingle');

            cycletype=app.CycletypeDropDown.Value;
            cyclenum=app.Cycle_numSpinner_3.Value;
            cycle_name=[cycletype,num2str(cyclenum,'%.2u')];
            app.person=app.TechnicianDropDown.Value;
            app.remote_storage=char(app.remote_loginEditField.Value);
            app.maxproj_folder=char(app.remote_rootEditField.Value);
            app.datafolder=char(app.ExperimentIDEditField.Value);
            
            switch cycletype
                case 'geneseq'
                    %tform=tformseq;
                    tform=tformsingle;
                    %dicch=2;
                    %focusch=1;
                    if cyclenum==1
                        
                        %ch_seq=[2 1 3 4 5]; tformch=[2 4];%original fast-seq
                        %ch_seq=[2 1 3 4 5 6 7] tformch=[2 4 6]; %fastseq with additiona C
                        ch_seq=[2 1 3 4 5]; tformch=[]; %single-seq
                    elseif cyclenum>1
                        %ch_seq=[2 1 3 4]; tformch=[2 4];
                        %ch_seq=[2 1 3 4 5 6];  tformch=[2 4 6];
                        ch_seq=[2 1 3 4]; tformch=[]; %single-seq
                    end
                case 'bcseq'
                    %tform=tformseq;
                    tform=tformsingle;
                    %dicch=2;
                    %focusch=1;
                    if cyclenum==1
                        %ch_seq=[2 1 3 4 5]; tformch=[2 4];%original fast-seq
                        %ch_seq=[2 1 3 4 5 6 7] tformch=[2 4 6]; %fastseq with additiona C
                        ch_seq=[2 1 3 4 5]; tformch=[]; %single-seq
                    else
                        %ch_seq=[2 1 3 4]; tformch=[2 4];
                        %ch_seq=[2 1 3 4 5 6];  tformch=[2 4 6];
                        ch_seq=[2 1 3 4]; tformch=[]; %single-seq
                    end
                case 'hyb'
                    %tform=tformhyb;
                    tform=tformsingle;
                    %dicch=2;
                    %focusch=1;
                    %ch_seq=[2 6 1 5 4 3]; tformch=[2 4 6]; % need to double check this
                    ch_seq=[1 5 2 6 3 4]; tformch=[]; %hyb using C1
            end
            

%            [~,I]=ismember(app.person,app.phonebook(:,1));
%            phone_num=char(app.phonebook(I,2));
%            serviceprovider=char(app.phonebook(I,3));
%             switch app.person
%                 case "Aixin Zhang'
%                     phone_num='1234567890';
%                     serviceprovider='tmobile';
%                 case 'User 2'
%                     phone_num='1234567890';
%                     serviceprovider='tmobile';
%             end
            
            %ch_seq=[2 1 3 4]; %if imaging without dic in a first cycle, use this.
             waitbar(.33,f,'Starting max projection');
             app.MessageTextArea.Value=sprintf('Starting max projection on cycle %s ', [cycletype,num2str(cyclenum,'%.2u')]);
             
             try
                niemaxprojimaging_gui(app,cycle_name,ch_seq,tformch,30,['Posinfo_regoffset',cycle_name,'.mat'], ...
                tform,app.remote_storage, ...
                [app.maxproj_root,app.experiment_id],fullfile(app.maxproj_root,app.experiment_id));
             catch ME % ME is of the class MException (in-built Matlab)
                str = sprintf(['Something is wrong with input files or setting. \n' 'Matlab says: \n' ME.message]);
                f_error = app.UIFigure;
                f_error.WindowStyle = 'modal';
                uialert(f_error, str, 'Input Files or Setting Error', 'Icon', 'error');
                close(f);
                app.InputcycleinformationButton.Enable='on';
                app.Step1CheckFocusingButton.Enable='on';
                app.Step2CheckAlignmentButton.Enable='on';
                app.CreateTilesButton.Enable='on';
                app.GenerateMaxProjectionButton.Enable='on';
               
                return
             end          
             app.MessageTextArea.Value=[app.MessageTextArea.Value(:)', {sprintf('Maxprojection Done!')}];
             % waitbar(.66,f,'transfering data files and checking if transfer is complete ...')
             % send_text_message(phone_num,serviceprovider,'Seq status:','Cycle finished');
             
             if ~isempty(app.remote_storage)
                [status,cmdout]=system(['ssh     ',app.remote_storage, ...
                    ' "ls ',app.maxproj_folder,app.datafolder,'/',cycle_name, ...
                    '/*.tif | wc -l" ']);
             end
             localfiles=dir(fullfile(app.Maxproj_drive,app.experiment_id,cycle_name,'*.tif'));
             
             if isempty(app.remote_storage)||numel(localfiles)==str2double(cmdout)
                app.MessageTextArea.Value=[app.MessageTextArea.Value(:)', {sprintf('Remote and Local file numbers match. Transfer complete!')}];
                aws=app.SendtoAWSDropDown.Value;
                switch aws
                    case 'Yes'
                    waitbar(0.88,f,' Maxprojection is done! Transfer files to AWS and Servers.');
                    app.MessageTextArea.Value=[app.MessageTextArea.Value(:)', {sprintf('Uploading to AWS')}];
                    if ~isempty(app.remote_storage)
                        transfer_to_data_storage(app.experiment_id,cycle_name);
                    end
                    pause(1)
                    AWS_upload(app.experiment_id,cycle_name)
                    close(f);
                    app.InputcycleinformationButton.Enable='on';
                    app.Step1CheckFocusingButton.Enable='on';
                    app.Step2CheckAlignmentButton.Enable='on';
                    app.CreateTilesButton.Enable='on';
                    app.GenerateMaxProjectionButton.Enable='on';

                case 'No'
                     waitbar(0.88,f,' Maxprojection is done! Transfer files to Servers.');
                     app.MessageTextArea.Value=[app.MessageTextArea.Value(:)', {sprintf('Maxprojection is done! Transfer files to Servers.')}];
                     if ~isempty(app.remote_storage)
                        transfer_to_data_storage(app.experiment_id,cycle_name);
                     end
                     close(f);
                     app.InputcycleinformationButton.Enable='on';
                     app.Step1CheckFocusingButton.Enable='on';
                     app.Step2CheckAlignmentButton.Enable='on';
                     app.CreateTilesButton.Enable='on';
                     app.GenerateMaxProjectionButton.Enable='on';
                end
            else
                close(f)
                send_text_message(phone_num,serviceprovider,'Seq error:','File numbers do not match');
                app.WarningTextArea.Value='Error: Remote and local file numbers do not match, please manually check files!';
                str = sprintf(['Remote and local file numbers do not match, please manually check files! \n' 'Matlab says: \n' ME.message]);
                f_error = app.UIFigure;
                f_error.WindowStyle = 'modal';
                uialert(f_error, str, 'Error', 'Icon', 'error');
                
                
                app.InputcycleinformationButton.Enable='on';
                app.Step1CheckFocusingButton.Enable='on';
                app.Step2CheckAlignmentButton.Enable='on';
                app.CreateTilesButton.Enable='on';
                app.GenerateMaxProjectionButton.Enable='on';
                
                error('Error: Remote and local file numbers do not match, please manually check files!');
             end 
            
        end

        % Value changed function: MessageTextArea
        function MessageTextAreaValueChanged(app, event)
            drawnow;
        end

        % Value changed function: WarningTextArea
        function WarningTextAreaValueChanged(app, event)
            drawnow;
            
        end

        % Callback function: ChangeCheckBox, TechnicianDropDown
        function ChangeCheckBoxValueChanged(app, event)
            value = app.ChangeCheckBox.Value;
            if value
               app.remote_rootEditField.Enable = 'on';
               app.remote_rootEditFieldLabel.Enable='on';
              
            else
               app.remote_rootEditField.Enable = 'off';
               app.remote_loginEditField.Enable='off';
           

            end
        end

        % Value changed function: ChangepixeisizeCheckBox
        function ChangepixeisizeCheckBoxValueChanged(app, event)
            value = app.ChangepixeisizeCheckBox.Value;
            if value
              app.pixelsizeEditField.Enable='on';
              app.pixelsizeEditFieldLabel.Enable='on'; 
            else
               app.pixelsizeEditField.Enable= 'off';
               app.pixelsizeEditFieldLabel.Enable='off';
            end
        end

        % Button pushed function: CheckDeleteButton
        function CheckDeleteButtonPushed(app, event)
             app.remote_rootEditField.Enable = 'off';
             app.remote_loginEditField.Enable='off';
            app.InputcycleinformationButton.Enable='off';
            app.Step1CheckFocusingButton.Enable='off';
            app.Step2CheckAlignmentButton.Enable='off';
            app.CreateTilesButton.Enable='off';
            app.GenerateMaxProjectionButton.Enable='off';
            app.experiment_id=char(app.DatafolderEditField_2.Value);
            cycletype=app.CycletypeDropDown_2.Value;
            cyclenum=app.Cycle_numSpinner_4.Value;
            cycle_name=[cycletype,num2str(cyclenum,'%.2u')];
            app.MessageTextArea.Value='Check the folder details';
          
            cd(app.Aws_management_dir);

            datafolderfull=fullfile(app.acquisition_drive,app.datafolder,cycle_name);
            filename=[app.datafolder,cycle_name,'.txt'];
            pause(2)
            cmd=append('aws s3 sync ',datafolderfull,' s3://barseqtest/',app.datafolder,'/',cycle_name,' --dryrun > ',filename);
            system(cmd);
            pause(2)
            app.MessageTextArea.Value=[app.MessageTextArea.Value(:)',{sprintf('Check AWS details')}];      
            t = readtable(filename,'ReadVariableNames',false);
          
            number=height(t);
            if number~=0
                app.WarningTextArea.FontColor=[1 0 0];
                app.WarningTextArea.Value=[app.MessageTextArea.Value(:)',{sprintf('Do not delete!,Missing files detected, details is saved in AWS Managemenr folder')}];
            else
                dict=fullfile(app.acquisition_drive,app.datafolder);
                cd(dict)
                app.MessageTextArea.Value=[app.MessageTextArea.Value(:)',{sprintf('All files are in AWS now, deleting the foldernow')}];
                rmdir(cycle_name, 's')
            end


        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            app.datafolder=char(app.DatafolderEditField_2.Value);
            cycletype=app.CycletypeDropDown_2.Value;
            cyclenum=app.Cycle_numSpinner_4.Value;
            cycle_name=[cycletype,num2str(cyclenum,'%.2u')];
            app.WarningTextArea.Value=[app.MessageTextArea.Value(:)',{sprintf('Resume uploading to AWS')}];
            datafolderfull=fullfile(app.acquisition_drive,app.datafolder,cycle_name);
            cmd=append('start aws s3 sync ',datafolderfull,' s3://barseqtest/',app.datafolder,'/',cycle_name,' --storage-class DEEP_ARCHIVE');
            systme(cmd);
            app.WarningTextArea.Value=[app.MessageTextArea.Value(:)',{sprintf('Done')}];
        end

        % Value changed function: TechnicianDropDown
        function TechnicianDropDownValueChanged(app, event)
            value = app.TechnicianDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.TechnicianDropDownLabel = uilabel(app.UIFigure);
            app.TechnicianDropDownLabel.Text = 'Technician';
            app.TechnicianDropDown = uidropdown(app.UIFigure);
            app.TechnicianDropDown.Items = {'Diana Ravens'};
            app.TechnicianDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ChangeCheckBoxValueChanged, true);
            app.TechnicianDropDown.ValueChangedFcn = createCallbackFcn(app, @TechnicianDropDownValueChanged, true);
            app.TechnicianDropDown.Value = 'Diana Ravens';
            app.ExperimentIDEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimentIDEditFieldLabel.Text = 'Experiment ID';
            app.ExperimentIDEditField = uieditfield(app.UIFigure, 'text');
            app.CycletypeDropDownLabel = uilabel(app.UIFigure);
            app.CycletypeDropDownLabel.Text = 'Cycletype';
            app.CycletypeDropDown = uidropdown(app.UIFigure);
            app.CycletypeDropDown.Items = {'geneseq', 'hyb', 'bcseq'};
            app.CycletypeDropDown.Value = 'geneseq';
            app.init_cycle_typeLabel = uilabel(app.UIFigure);
            app.init_cycle_typeLabel.Text = 'init_cycle_type';
            app.init_cycle_typeDropDown = uidropdown(app.UIFigure);
            app.init_cycle_typeDropDown.Items = {'geneseq', 'hyb', 'bc_seq'};
            app.init_cycle_typeDropDown.Value = 'geneseq';
            app.Step1CheckFocusingButton = uibutton(app.UIFigure, 'push');
            app.Step1CheckFocusingButton.ButtonPushedFcn = createCallbackFcn(app, @Step1CheckFocusingButtonPushed, true);
            app.Step1CheckFocusingButton.Text = 'Step1 Check Focusing';
            app.GenerateMaxProjectionButton = uibutton(app.UIFigure, 'push');
            app.GenerateMaxProjectionButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMaxProjectionButtonPushed, true);
            app.GenerateMaxProjectionButton.Text = 'Generate Max Projection';
            app.CreateTilesButton = uibutton(app.UIFigure, 'push');
            app.CreateTilesButton.ButtonPushedFcn = createCallbackFcn(app, @CreateTilesButtonPushed, true);
            app.CreateTilesButton.Text = 'Create Tiles';
            app.Section2FocusingLabel = uilabel(app.UIFigure);
            app.Section2FocusingLabel.Text = 'Section 2 Focusing';
            app.Section3CreateTilesLabel = uilabel(app.UIFigure);
            app.Section3CreateTilesLabel.Text = 'Section 3 Create Tiles';
            app.Section4MaxprojectionLabel = uilabel(app.UIFigure);
            app.Section4MaxprojectionLabel.Text = 'Section 4 Maxprojection';
            app.Cycle_numSpinner_3Label = uilabel(app.UIFigure);
            app.Cycle_numSpinner_3Label.Text = 'Cycle_num';
            app.Cycle_numSpinner_3 = uispinner(app.UIFigure);
            app.Cycle_numSpinner_3.Limits = [0 16];
            app.InputcycleinformationButton = uibutton(app.UIFigure, 'push');
            app.InputcycleinformationButton.ButtonPushedFcn = createCallbackFcn(app, @InputcycleinformationButtonPushed, true);
            app.InputcycleinformationButton.Text = 'Input cycle information';
            app.remote_loginEditFieldLabel = uilabel(app.UIFigure);
            app.remote_loginEditFieldLabel.Enable = 'off';
            app.remote_loginEditFieldLabel.Text = 'remote_login';
            app.remote_loginEditField = uieditfield(app.UIFigure, 'text');
            app.remote_loginEditField.Enable = 'off';
            app.remote_loginEditField.Value = 'user@bamdev1';
            app.remote_rootEditFieldLabel = uilabel(app.UIFigure);
            app.remote_rootEditFieldLabel.Enable = 'off';
            app.remote_rootEditFieldLabel.Text = 'remote_root';
            app.remote_rootEditField = uieditfield(app.UIFigure, 'text');
            app.remote_rootEditField.Enable = 'off';
            app.remote_rootEditField.Value = '/grid/mbseq/data_norepl/barseq/raw_data';
            app.pixelsizeEditFieldLabel = uilabel(app.UIFigure);
            app.pixelsizeEditFieldLabel.Enable = 'off';
            app.pixelsizeEditFieldLabel.Text = 'pixelsize';
            app.pixelsizeEditField = uieditfield(app.UIFigure, 'text');
            app.pixelsizeEditField.Enable = 'off';
            app.pixelsizeEditField.Value = '0.33';
            app.MessageTextArea = uitextarea(app.UIFigure);
            app.MessageTextArea.ValueChangedFcn = createCallbackFcn(app, @MessageTextAreaValueChanged, true);
            app.MessageTextAreaLabel_2 = uilabel(app.UIFigure);
            app.MessageTextAreaLabel_2.Text = 'Message';
            app.WarningTextArea = uitextarea(app.UIFigure);
            app.WarningTextArea.ValueChangedFcn = createCallbackFcn(app, @WarningTextAreaValueChanged, true);
            app.WarningTextAreaLabel_2 = uilabel(app.UIFigure);
            app.WarningTextAreaLabel_2.Text = 'Warning';
            app.Step2CheckAlignmentButton = uibutton(app.UIFigure, 'push');
            app.Step2CheckAlignmentButton.ButtonPushedFcn = createCallbackFcn(app, @Step2CheckAlignmentButtonPushed, true);
            app.Step2CheckAlignmentButton.Text = 'Step2 Check Alignment';
            app.Section1ImagingSettingLabel_2 = uilabel(app.UIFigure);
            app.Section1ImagingSettingLabel_2.Text = 'Section 1 Imaging Setting';
            app.SlicePerSlideEditFieldLabel = uilabel(app.UIFigure);
            app.SlicePerSlideEditFieldLabel.Text = 'Slice Per Slide';
            app.SlicePerSlideEditField = uieditfield(app.UIFigure, 'text');
            app.ChangeCheckBox = uicheckbox(app.UIFigure);
            app.ChangeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChangeCheckBoxValueChanged, true);
            app.ChangeCheckBox.Text = {'Change'; ''};
            app.ChangepixeisizeCheckBox = uicheckbox(app.UIFigure);
            app.ChangepixeisizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChangepixeisizeCheckBoxValueChanged, true);
            app.ChangepixeisizeCheckBox.Text = 'Change pixeisize';
            app.DeleteResumeUploadingfolderandfilesPanel = uipanel(app.UIFigure);
            app.DeleteResumeUploadingfolderandfilesPanel.Title = 'Delete/Resume Uploading folder and files';
            app.DatafolderEditField_2Label = uilabel(app.DeleteResumeUploadingfolderandfilesPanel);
            app.DatafolderEditField_2Label.Text = 'Datafolder';
            app.DatafolderEditField_2 = uieditfield(app.DeleteResumeUploadingfolderandfilesPanel, 'text');
            app.CycletypeDropDown_2Label = uilabel(app.DeleteResumeUploadingfolderandfilesPanel);
            app.CycletypeDropDown_2Label.Text = 'Cycletype';
            app.CycletypeDropDown_2 = uidropdown(app.DeleteResumeUploadingfolderandfilesPanel);
            app.CycletypeDropDown_2.Items = {'geneseq', 'hyb', 'bcseq'};
            app.CycletypeDropDown_2.Value = 'geneseq';
            app.Cycle_numSpinner_4Label = uilabel(app.DeleteResumeUploadingfolderandfilesPanel);
            app.Cycle_numSpinner_4Label.Text = 'Cycle_num';
            app.Cycle_numSpinner_4 = uispinner(app.DeleteResumeUploadingfolderandfilesPanel);
            app.Cycle_numSpinner_4.Limits = [1 16];
            app.Cycle_numSpinner_4.Value = 1;
            app.CheckDeleteButton = uibutton(app.DeleteResumeUploadingfolderandfilesPanel, 'push');
            app.CheckDeleteButton.ButtonPushedFcn = createCallbackFcn(app, @CheckDeleteButtonPushed, true);
            app.CheckDeleteButton.Text = 'Check & Delete';
            app.ResumeButton = uibutton(app.DeleteResumeUploadingfolderandfilesPanel, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.SendtoAWSDropDownLabel = uilabel(app.UIFigure);
            app.SendtoAWSDropDownLabel.Text = 'Send to AWS';
            app.SendtoAWSDropDown = uidropdown(app.UIFigure);
            app.SendtoAWSDropDown.Items = {'No', 'Yes', ''};
            app.SendtoAWSDropDown.Value = 'No';
            app.Section5ArchiveLocallyLabel = uilabel(app.UIFigure);
            app.Section5ArchiveLocallyLabel.Text = 'Section 5 Archive Locally';
            app.Section6ArchiveRemotelyLabel = uilabel(app.UIFigure);
            app.Section6ArchiveRemotelyLabel.Text = 'Section 6 Archive Remotely';
            app.ArchiveLocallyButton = uibutton(app.UIFigure, 'push');
            app.ArchiveLocallyButton.Text = 'Archive Locally';
            app.ArchiveRemotelyButton = uibutton(app.UIFigure, 'push');
            app.ArchiveRemotelyButton.Text = 'Archive Remotely';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Imaging_GUI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end